classdef BasicGUINewRev1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        RobotOperationsPanel            matlab.ui.container.Panel
        StartUpRobotButton              matlab.ui.control.Button
        ResumeRobotButton               matlab.ui.control.Button
        ShutDownRobotButton             matlab.ui.control.Button
        RobotOperationStatusLampLabel   matlab.ui.control.Label
        RobotOperationStatusLamp        matlab.ui.control.Lamp
        PauseRobotButton                matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        ErrorsPanel                     matlab.ui.container.Panel
        EmergencyStopLamp_2Label        matlab.ui.control.Label
        EmergencyStopLamp               matlab.ui.control.Lamp
        LightCurtainBrokenLampLabel     matlab.ui.control.Label
        LightCurtainBrokenLamp          matlab.ui.control.Lamp
        ConveyorInterlockBrokenLampLabel  matlab.ui.control.Label
        ConveyorInterlockBrokenLamp     matlab.ui.control.Lamp
        RobotMotionLimitationLampLabel  matlab.ui.control.Label
        RobotMotionLimitationLamp       matlab.ui.control.Lamp
        TaskCompletionStatusPanel_2     matlab.ui.container.Panel
        ReadyforCustomerOrderLampLabel  matlab.ui.control.Label
        ReadyforCustomerOrderLamp       matlab.ui.control.Lamp
        TracingoutEdibleInkTextLamp_3Label  matlab.ui.control.Label
        TracingoutEdibleInkTextLamp     matlab.ui.control.Lamp
        CAKEREADYLampLabel              matlab.ui.control.Label
        CAKEREADYLamp                   matlab.ui.control.Lamp
        PlacingLetterBlocksLamp_3Label  matlab.ui.control.Label
        PlacingLetterBlocksLamp         matlab.ui.control.Lamp
        TextArea                        matlab.ui.control.TextArea
        DIOsPanel                       matlab.ui.container.Panel
        MotorsONLampLabel               matlab.ui.control.Label
        MotorsONLamp                    matlab.ui.control.Lamp
        MotorsEnabledLampLabel          matlab.ui.control.Label
        MotorsEnabledLamp               matlab.ui.control.Lamp
        ResetIOsButton                  matlab.ui.control.Button
        ConveyorLabel                   matlab.ui.control.Label
        VacuumLabel                     matlab.ui.control.Label
        ConRunButton                    matlab.ui.control.StateButton
        DirectionSwitchLabel            matlab.ui.control.Label
        DirectionSwitch                 matlab.ui.control.Switch
        VacSolSwitchLabel               matlab.ui.control.Label
        VacSolSwitch                    matlab.ui.control.Switch
        VacRunButton                    matlab.ui.control.StateButton
        ConRunLampLabel                 matlab.ui.control.Label
        ConRunLamp                      matlab.ui.control.Lamp
        VacSolLampLabel                 matlab.ui.control.Label
        VacSolLamp                      matlab.ui.control.Lamp
        VacRunLampLabel                 matlab.ui.control.Label
        VacRunLamp                      matlab.ui.control.Lamp
        ConveyorStatusLamp_2Label       matlab.ui.control.Label
        ConveyorStatusLamp              matlab.ui.control.Lamp
        ManagerLoginEditFieldLabel      matlab.ui.control.Label
        ManagerLoginEditField           matlab.ui.control.EditField
        LoginStatusLampLabel            matlab.ui.control.Label
        LoginStatusLamp                 matlab.ui.control.Lamp
        LogoutButton                    matlab.ui.control.Button
        ConnectionStatusLampLabel       matlab.ui.control.Label
        ConnectionStatusLamp            matlab.ui.control.Lamp
        ReconnectButton                 matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        CameraFeedTab                   matlab.ui.container.Tab
        ConVidAxes                      matlab.ui.control.UIAxes
        TabVidAxes                      matlab.ui.control.UIAxes
        TracedAxes                      matlab.ui.control.UIAxes
        StartupTab                      matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        AcknowledgeButton               matlab.ui.control.Button
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        ShutdownTab                     matlab.ui.container.Tab
        PleaseensurethefollowingSHUTDOWNproceduresarecompletedLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        JoggingmustbeperformedtoensureRobotisinHOMEPOSITIONLabel  matlab.ui.control.Label
        Label3_2                        matlab.ui.control.Label
        AcknowledgeButton_2             matlab.ui.control.Button
        EStopTab                        matlab.ui.container.Tab
        ERROREMERGENCYSTOPLabel         matlab.ui.control.Label
        EmergencyStophasbeentriggeredPleaseensurethefollowingLabel  matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        CheckButton_3                   matlab.ui.control.Button
        PleaseclickCheckoncetheproblemisresolvedLabel_3  matlab.ui.control.Label
        LightTab                        matlab.ui.container.Tab
        ERRORLIGHTCURTAINLabel          matlab.ui.control.Label
        LightCurtainshavebeentriggeredPleaseensurethefollowingLabel  matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        CheckButton_2                   matlab.ui.control.Button
        PleaseclickCheckoncetheproblemisresolvedLabel_2  matlab.ui.control.Label
        ConveyTab                       matlab.ui.container.Tab
        ERRORCONVEYINTERLOCKTRIGGEREDLabel  matlab.ui.control.Label
        ConveyorInterlockhavebeentriggeredPleaseensurethefollowingLabel  matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        CheckButton_4                   matlab.ui.control.Button
        PleaseclickCheckoncetheproblemisresolvedLabel_4  matlab.ui.control.Label
        OrderHereTab                    matlab.ui.container.Tab
        DECORATEORDERYOURCAKEHERELabel  matlab.ui.control.Label
        LetterDropDown_1                matlab.ui.control.DropDown
        ReviewOrderButton               matlab.ui.control.Button
        LetterDropDown_2                matlab.ui.control.DropDown
        LetterDropDown_3                matlab.ui.control.DropDown
        LetterDropDown_4                matlab.ui.control.DropDown
        LetterDropDown_5                matlab.ui.control.DropDown
        LetterDropDown_6                matlab.ui.control.DropDown
        LetterDropDown_7                matlab.ui.control.DropDown
        LetterDropDown_8                matlab.ui.control.DropDown
        LetterDropDown_9                matlab.ui.control.DropDown
        LetterDropDown_10               matlab.ui.control.DropDown
        LetterDropDown_11               matlab.ui.control.DropDown
        LetterDropDown_12               matlab.ui.control.DropDown
        LetterDropDown_13               matlab.ui.control.DropDown
        LetterDropDown_14               matlab.ui.control.DropDown
        LetterDropDown_15               matlab.ui.control.DropDown
        LetterDropDown_16               matlab.ui.control.DropDown
        LetterDropDown_17               matlab.ui.control.DropDown
        LetterDropDown_18               matlab.ui.control.DropDown
        LetterDropDown_19               matlab.ui.control.DropDown
        LetterDropDown_20               matlab.ui.control.DropDown
        LetterDropDown_21               matlab.ui.control.DropDown
        LetterDropDown_22               matlab.ui.control.DropDown
        LetterDropDown_23               matlab.ui.control.DropDown
        LetterDropDown_24               matlab.ui.control.DropDown
        LetterDropDown_25               matlab.ui.control.DropDown
        LetterDropDown_26               matlab.ui.control.DropDown
        LetterDropDown_27               matlab.ui.control.DropDown
        LetterDropDown_28               matlab.ui.control.DropDown
        LetterDropDown_29               matlab.ui.control.DropDown
        LetterDropDown_30               matlab.ui.control.DropDown
        LetterDropDown_31               matlab.ui.control.DropDown
        LetterDropDown_32               matlab.ui.control.DropDown
        LetterDropDown_33               matlab.ui.control.DropDown
        LetterDropDown_34               matlab.ui.control.DropDown
        LetterDropDown_35               matlab.ui.control.DropDown
        LetterDropDown_36               matlab.ui.control.DropDown
        LetterDropDown_37               matlab.ui.control.DropDown
        LetterDropDown_38               matlab.ui.control.DropDown
        LetterDropDown_39               matlab.ui.control.DropDown
        LetterDropDown_40               matlab.ui.control.DropDown
        LetterDropDown_41               matlab.ui.control.DropDown
        LetterDropDown_42               matlab.ui.control.DropDown
        LetterDropDown_43               matlab.ui.control.DropDown
        LetterDropDown_44               matlab.ui.control.DropDown
        LetterDropDown_45               matlab.ui.control.DropDown
        LetterDropDown_46               matlab.ui.control.DropDown
        LetterDropDown_47               matlab.ui.control.DropDown
        LetterDropDown_48               matlab.ui.control.DropDown
        LetterDropDown_49               matlab.ui.control.DropDown
        LetterDropDown_50               matlab.ui.control.DropDown
        LetterDropDown_51               matlab.ui.control.DropDown
        LetterDropDown_52               matlab.ui.control.DropDown
        LetterDropDown_53               matlab.ui.control.DropDown
        LetterDropDown_54               matlab.ui.control.DropDown
        LetterDropDown_55               matlab.ui.control.DropDown
        LetterDropDown_56               matlab.ui.control.DropDown
        LetterDropDown_57               matlab.ui.control.DropDown
        LetterDropDown_58               matlab.ui.control.DropDown
        LetterDropDown_59               matlab.ui.control.DropDown
        LetterDropDown_60               matlab.ui.control.DropDown
        LetterDropDown_61               matlab.ui.control.DropDown
        LetterDropDown_62               matlab.ui.control.DropDown
        LetterDropDown_63               matlab.ui.control.DropDown
        LetterDropDown_64               matlab.ui.control.DropDown
        LetterDropDown_65               matlab.ui.control.DropDown
        LetterDropDown_66               matlab.ui.control.DropDown
        LetterDropDown_67               matlab.ui.control.DropDown
        LetterDropDown_68               matlab.ui.control.DropDown
        LetterDropDown_69               matlab.ui.control.DropDown
        LetterDropDown_70               matlab.ui.control.DropDown
        LetterDropDown_71               matlab.ui.control.DropDown
        LetterDropDown_72               matlab.ui.control.DropDown
        LetterDropDown_73               matlab.ui.control.DropDown
        LetterDropDown_74               matlab.ui.control.DropDown
        LetterDropDown_75               matlab.ui.control.DropDown
        LetterDropDown_76               matlab.ui.control.DropDown
        LetterDropDown_77               matlab.ui.control.DropDown
        LetterDropDown_78               matlab.ui.control.DropDown
        LetterDropDown_79               matlab.ui.control.DropDown
        LetterDropDown_80               matlab.ui.control.DropDown
        LetterDropDown_81               matlab.ui.control.DropDown
        ReviewTab                       matlab.ui.container.Tab
        IsyourordercorrectIfyesSENDITIfnoFixItLabel  matlab.ui.control.Label
        FixupmyorderButton_2            matlab.ui.control.Button
        SENDITButton_2                  matlab.ui.control.Button
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = public)
        StartAcknowledgeButton_Pressed = 0;
        StartUpRobotButton_Pressed = 0;
        ShutDownRobotButton_Pressed = 0;
        VacRunButton_Pressed = 0;
        ConRunButton_Pressed = 0;
        DirectionSwitch_Changed = 0;
        VacSolSwitch_Changed = 0;
        ManagerAccess_Granted = 0;
        TextDisplay = [];
    end
    
    properties (Access = private)
        vidTable;
        TabVidHandleOverview;
    end
    
    methods (Access = private)
        
         % Start the requested overview video
        function StartOverviewVideo(app,VidName)
            if (strcmp(VidName, 'Table'))
                % Prep video object for manual and start it
                app.vidTable = videoinput('winvideo', 1);%, 'MJPG_1600x1200');
                app.vidTable.ReturnedColorSpace = 'rgb';
                triggerconfig(app.vidTable, 'manual');
                start(app.vidTable);
                % Prep axis handle
                video_resolution1 = app.vidTable.VideoResolution;
                nbands1 = app.vidTable.NumberOfBands;
                app.TabVidHandleOverview = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.TabVidAxes);
%                 app.TabVidHandleOverview = imshow(zeros([720, 720, nbands1]), 'Parent', app.TabVidAxes);
%                 app.TabVidHandleOverview = imcrop(app.TabVidHandleOverview,[450 120 600 600]);
                preview(app.vidTable,app.TabVidHandleOverview);
            end 
            if (strcmp(VidName, 'Conveyor'))
                % Prep video object for manual and start it
                app.vidTable = videoinput('winvideo', 2);%, 'MJPG_1600x1200');
                app.vidTable.ReturnedColorSpace = 'rgb';
                triggerconfig(app.vidTable, 'manual');
                start(app.vidTable);
                % Prep axis handle
                video_resolution1 = app.vidTable.VideoResolution;
                nbands1 = app.vidTable.NumberOfBands;
                app.TabVidHandleOverview = imshow(zeros([video_resolution1(2), video_resolution1(1), nbands1]), 'Parent', app.ConVidAxes);
%                 app.TabVidHandleOverview = imshow(zeros([720, 720, nbands1]), 'Parent', app.ConVidAxes);
%                 app.TabVidHandleOverview = imcrop(app.TabVidHandleOverview,[450 120 600 600]);
                preview(app.vidTable,app.TabVidHandleOverview);
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imaqreset;
            % Start the video feeds
            % Table Camera
            try
                StartOverviewVideo(app, 'Table')
            catch
                warning('Problem starting table camera');
            end
             % Conveyor Camera
            try
                StartOverviewVideo(app, 'Conveyor')
            catch 
                warning('Problem starting conveyor camera');
            end
        end

        % Button pushed function: StartUpRobotButton
        function StartUpRobotButtonPushed(app, event)
            app.TextArea.Value = 'IRB120 Starting Up, Enter Manager Password';    
%             if (app.StartAcknowledgeButton_Pressed == 1)
%                 if(app.ManagerAccess_Granted == 1)
                    app.TextArea.Value =  'Starting Up Successful. Hold delete for 1 second to clear textbox.';  
                    app.RobotOperationStatusLamp.Color = [0 1 0];
                    app.ReadyforCustomerOrderLamp.Color = [0 1 0];
                    app.StartUpRobotButton.Visible = 0;
                    app.PauseRobotButton.Visible = 1;
                    app.ShutDownRobotButton.Visible = 1;
                    app.VacRunButton_Pressed = 0;
                    app.ConRunButton_Pressed = 0;
                    app.VacRunButton.Enable = 1;
                    app.ConRunButton.Enable = 1;
                    app.VacSolSwitch.Enable = 1;       
                    app.ResetIOsButton.Enable = 1;
                    app.DirectionSwitch.Enable = 1;
                    app.ManagerLoginEditField.Value = ' ';
                    app.StartUpRobotButton_Pressed = 1;
%                 else
%                     app.TextArea.Value = 'Starting Up Failed! Hold delete for 1 second to clear textbox then enter Manager Password';   
%                     app.StartUpRobotButton_Pressed = 0;
%                     app.ManagerLoginEditField.Value = ' ';
% 
%                 end
%             end    
        end

        % Button pushed function: ShutDownRobotButton
        function ShutDownRobotButtonPushed(app, event)
%             app.TextArea.Value = 'IRB120 Shutting down. Enter Manager Password.';  
%             app.TextArea_2.Value = 'IRB120 Shutting down...'; 
%             run('Shutdown.mlapp');
            if(app.ManagerAccess_Granted == 1)
%                 app.ManagerLoginEditField.Value = ' ';
%                 app.ManagerLoginEditField.reset;
                app.ShutDownRobotButton_Pressed = 1;
                app.TabGroup.SelectedTab = app.ShutdownTab;
            else
                app.ManagerLoginEditField.Value = ' ';
                app.TextArea.Value = 'Shut Down Failed. Hold delete for 1 second to clear textbox then enter Manager Password';
                app.ShutDownRobotButton_Pressed = 0;
            end
%             app.ManagerAccess_Granted = 0;
        end

        % Button pushed function: PauseRobotButton
        function PauseRobotButtonPushed(app, event)
            app.ResumeRobotButton.Visible = 1;
            app.PauseRobotButton.Visible = 0;
            app.ShutDownRobotButton.Visible = 1;
            app.RobotOperationStatusLamp.Color = [1 1 0];
            app.TextArea.Value = 'Robot Paused';  
        end

        % Button pushed function: ResumeRobotButton
        function ResumeRobotButtonPushed(app, event)
            app.ResumeRobotButton.Visible = 0;
            app.PauseRobotButton.Visible = 1;
            app.ShutDownRobotButton.Visible = 1;
            app.RobotOperationStatusLamp.Color = [0 1 0];
            app.TextArea.Value = 'Robot Resumed';  
        end

        % Button pushed function: AcknowledgeButton
        function AcknowledgeButtonPushed(app, event)
%           START MENU ACKNOWLEDGE BUTTON
%             if (app.CheckBox.Value == 1 &&...
%                 app.CheckBox_2.Value == 1 &&...
%                 app.CheckBox_3.Value == 1 &&...
%                 app.CheckBox_4.Value == 1 &&...
%                 app.CheckBox_5.Value == 1 &&...
%                 app.CheckBox_6.Value == 1 &&...
%                 app.CheckBox_7.Value == 1)
            app.StartAcknowledgeButton_Pressed = 1;
            app.StartUpRobotButton.Enable = 1;
            app.TabGroup.SelectedTab = app.HomeTab;
             app.TextArea.Value = 'Enter Manager Password to Start Robot';
%             else
%                 msgbox('READ AND ACKNOWLEDGE BEFORE PROCEEDING');
%             end
        end

        % Value changed function: ConRunButton
        function ConRunButtonValueChanged(app, event)
            value = app.ConRunButton.Value
%             if(app.ManagerAccess_Granted == 1)
                if(value==1)
                    app.TextArea.Value = 'Conveyor Running...';
    %                 app.TextArea_2.Value = 'Conveyor Running...';
                    app.ConRunLamp.Color = [0 1 0];
                else
                    app.TextArea.Value = 'Conveyor Stopped';
    %                 app.TextArea_2.Value = 'Conveyor stopped';
                    app.ConRunLamp.Color = [1 0 0];
                end
%                 app.ConRunButton_Pressed = 1 - app.ConRunButton_Pressed;
%             else
%                 app.TextArea.Value = 'Manager access required to operate conveyor';
%             end
            app.ConRunButton_Pressed = 1;
        end

        % Value changed function: VacRunButton
        function VacRunButtonValueChanged(app, event)
            value = app.VacRunButton.Value
%             if(app.ManagerAccess_Granted == 1)
                if(value==1)
                    app.TextArea.Value = 'Vacuum Running...';
                    app.VacRunLamp.Color = [0 1 0];
                else
                    app.TextArea.Value = 'Vacuum Stopped';
                    app.VacRunLamp.Color = [1 0 0];
                end
%             else
%                 app.TextArea.Value = 'Manager access required to operate vaccuum';
%             end
            app.VacRunButton_Pressed = 1;
        end

        % Button pushed function: AcknowledgeButton_2
        function AcknowledgeButton_2Pushed(app, event)
            if (app.ShutDownRobotButton_Pressed == 1)
                app.TextArea.Value = 'Shutdown Successful';  
                app.RobotOperationStatusLamp.Color = [1 0 0];
                app.StartUpRobotButton.Visible = 1;
                app.PauseRobotButton.Visible = 0;
                app.ShutDownRobotButton.Visible = 0;
                app.ShutDownRobotButton_Pressed = 0;
                app.TabGroup.SelectedTab = app.HomeTab;
            else % if(app.ShutDownRobotButton_Pressed == 0)
                app.TextArea.Value = 'Shutdown Failed: Manager access required';  
                app.ShutDownRobotButton_Pressed = 0;
                app.TabGroup.SelectedTab = app.HomeTab;
            end
        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
           value = app.DirectionSwitch.Value
%            if(app.ManagerAccess_Granted == 1)
                if (strcmp(value,'Forward'))
                    app.TextArea.Value = 'Conveyor Direction Forward';  
                    app.DirectionSwitch_Changed = 1;
                else % if (strcmp(value,'Backward'))
                    app.TextArea.Value = 'Conveyor Direction Reverse';  
                    app.DirectionSwitch_Changed = 0;
                end
%            end
%            app.DirectionSwitch_Changed = 1 - app.DirectionSwitch_Changed;
            app.DirectionSwitch_Changed = 1;
        end

        % Button pushed function: ResetIOsButton
        function ResetIOsButtonPushed(app, event)
            if(app.ManagerAccess_Granted == 1)    
                app.DirectionSwitch.Value = 'Backward';
                app.ConRunButton.Value = 0;
                app.ConRunLamp.Color = [1 0 0];
                app.VacSolSwitch.Value = 'Off';
                app.VacRunButton.Value = 0;
                app.VacRunLamp.Color = [1 0 0];                
                app.VacSolLamp.Color = [1 0 0];
                app.TextArea.Value = 'DIO Reset';  
            end
        end

        % Value changed function: VacSolSwitch
        function VacSolSwitchValueChanged(app, event)
            value = app.VacSolSwitch.Value
%             if(app.ManagerAccess_Granted == 1)    
                if (strcmp(value,'On'))
                    app.TextArea.Value = 'Vac Sol Running...';
                    app.VacSolLamp.Color = [0 1 0];
                else
                    app.TextArea.Value = 'Vac   Sol Stopped';
                    app.VacSolLamp.Color = [1 0 0];
                end
%             end
            app.VacSolSwitch_Changed = 1;
        end

        % Button pushed function: CheckButton_3
        function CheckButtonPushed(app, event)
            app.EmergencyStopLamp.Color = [0 1 0];
            app.DirectionSwitch.Value = 'Backward';
            app.ConRunButton.Value = 0;
            app.ConRunLamp.Color = [1 0 0];
            app.VacSolSwitch.Value = 'Off';
            app.VacRunButton.Value = 0;
            app.StartUpRobotButton.Visible = 1;
            app.PauseRobotButton.Visible = 0;
            app.ShutDownRobotButton.Visible = 0;
            app.VacSolLamp.Color = [1 0 0];
            app.TextArea.Value = 'Restart Robot using normal procedure.';  
            app.TabGroup.SelectedTab = app.HomeTab;
            
        end

        % Value changed function: ManagerLoginEditField
        function ManagerLoginEditFieldValueChanged(app, event)
            value = app.ManagerLoginEditField.Value;
            if (strcmp(value,'4230'))
                app.TextArea.Value = 'Manager Access Granted. Hold delete for 1 second to clear textbox.';  
                app.LoginStatusLamp.Color = [0 1 0];
                app.LogoutButton.Enable = 1;
                app.ManagerLoginEditField.Value = ' ';
                app.ManagerAccess_Granted = 1;
            else
                app.ManagerAccess_Granted = 0;
                app.TextArea.Value = 'Manager Access Denied. Hold delete for 1 second to clear textbox then enter Manager Password';  
                app.LoginStatusLamp.Color = [1 0 0];
                app.ManagerLoginEditField.Value = ' ';
            end
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.ManagerAccess_Granted = 0;
            app.VacRunButton.Enable = 0;
            app.ConRunButton.Enable = 0;
            app.VacSolSwitch.Enable = 0;
            app.DirectionSwitch.Enable = 0;
            app.ResetIOsButton.Enable = 0;
            app.LoginButton.Enable = 1;
            app.LoginButton.Visible = 1;
            app.LoginStatusLamp.Color = [1 0 0];
            app.LogoutButton.Enable = 0;
            app.PauseRobotButton.Enable = 0;
            app.ShutDownRobotButton.Enable = 0;
            app.TextArea.Value = 'Manager Logout';  
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            if(app.ManagerAccess_Granted == 1)
                app.VacRunButton.Enable = 1;
                app.ConRunButton.Enable = 1;
                app.VacSolSwitch.Enable = 1;
                app.DirectionSwitch.Enable = 1;
                app.ResetIOsButton.Enable = 1;
                app.LoginStatusLamp.Color = [0 1 0];
                app.LogoutButton.Enable = 1;
                app.PauseRobotButton.Enable = 1;
                app.ShutDownRobotButton.Enable = 1;
                app.ManagerLoginEditField.Value = ' ';
                app.TextArea.Value = 'Manager Login Success. Hold delete for 1 second to clear textbox.';  
                app.LoginButton.Visible = 0;
                app.LoginButton.Enable = 0;
            else
                app.TextArea.Value = 'Hold delete for 1 second to clear textbox then enter Manager Password.';  
            end
        end

        % Button pushed function: ReviewOrderButton
        function ReviewOrderButtonPushed(app, event)
                    row1=[{app.LetterDropDown_1.Value},{app.LetterDropDown_2.Value},{app.LetterDropDown_3.Value},...
                          {app.LetterDropDown_4.Value},{app.LetterDropDown_5.Value},{app.LetterDropDown_6.Value},...
                          {app.LetterDropDown_7.Value},{app.LetterDropDown_8.Value},{app.LetterDropDown_9.Value},...
                          {app.LetterDropDown_10.Value},{app.LetterDropDown_11.Value},{app.LetterDropDown_12.Value},...                        
                          {app.LetterDropDown_13.Value},{app.LetterDropDown_14.Value},{app.LetterDropDown_15.Value},...
                          {app.LetterDropDown_16.Value},{app.LetterDropDown_17.Value},{app.LetterDropDown_18.Value},...
                          {app.LetterDropDown_19.Value},{app.LetterDropDown_20.Value},{app.LetterDropDown_21.Value},...
                          {app.LetterDropDown_22.Value},{app.LetterDropDown_23.Value},{app.LetterDropDown_24.Value},...
                          {app.LetterDropDown_25.Value},{app.LetterDropDown_26.Value},{app.LetterDropDown_27.Value},...
                          {app.LetterDropDown_28.Value},{app.LetterDropDown_29.Value},{app.LetterDropDown_30.Value},...
                          {app.LetterDropDown_31.Value},{app.LetterDropDown_32.Value},{app.LetterDropDown_33.Value},...
                          {app.LetterDropDown_34.Value},{app.LetterDropDown_35.Value},{app.LetterDropDown_36.Value},...
                          {app.LetterDropDown_37.Value},{app.LetterDropDown_38.Value},{app.LetterDropDown_39.Value},...
                          {app.LetterDropDown_40.Value},{app.LetterDropDown_41.Value},{app.LetterDropDown_42.Value},...
                          {app.LetterDropDown_43.Value},{app.LetterDropDown_44.Value},{app.LetterDropDown_45.Value},...
                          {app.LetterDropDown_46.Value},{app.LetterDropDown_47.Value},{app.LetterDropDown_48.Value},...
                          {app.LetterDropDown_49.Value},{app.LetterDropDown_50.Value},{app.LetterDropDown_51.Value},...
                          {app.LetterDropDown_52.Value},{app.LetterDropDown_53.Value},{app.LetterDropDown_54.Value},...
                          {app.LetterDropDown_55.Value},{app.LetterDropDown_56.Value},{app.LetterDropDown_57.Value},...
                          {app.LetterDropDown_58.Value},{app.LetterDropDown_59.Value},{app.LetterDropDown_60.Value},...
                          {app.LetterDropDown_61.Value},{app.LetterDropDown_62.Value},{app.LetterDropDown_63.Value},...
                          {app.LetterDropDown_64.Value},{app.LetterDropDown_65.Value},{app.LetterDropDown_66.Value},...
                          {app.LetterDropDown_67.Value},{app.LetterDropDown_68.Value},{app.LetterDropDown_69.Value},...
                          {app.LetterDropDown_70.Value},{app.LetterDropDown_71.Value},{app.LetterDropDown_72.Value},...
                          {app.LetterDropDown_73.Value},{app.LetterDropDown_74.Value},{app.LetterDropDown_75.Value},...
                          {app.LetterDropDown_76.Value},{app.LetterDropDown_77.Value},{app.LetterDropDown_78.Value},...
                          {app.LetterDropDown_79.Value},{app.LetterDropDown_80.Value},{app.LetterDropDown_81.Value}];
                     
                         Bp=imread('B+.jpg');
                         Bsq=imread('Bsq.jpg');
                         Bc=imread('Bc.jpg');
                         Bd=imread('Bd.jpg');
                         Bst=imread('Bst.jpg');
                         Bx=imread('Bx.jpg');
                         Gp=imread('G+.jpg');
                         Gsq=imread('Gsq.jpg');
                         Gc=imread('Gc.jpg');
                         Gd=imread('Gd.jpg');
                         Gst=imread('Gst.jpg');
                         Gx=imread('Gx.jpg');                    
                         Yp=imread('Y+.jpg');
                         Ysq=imread('Ysq.jpg');
                         Yc=imread('Yc.jpg');
                         Yd=imread('Yd.jpg');
                         Yst=imread('Yst.jpg');
                         Yx=imread('Yx.jpg');
                         Rp=imread('R+.jpg');
                         Rsq=imread('Rsq.jpg');
                         Rc=imread('Rc.jpg');
                         Rd=imread('Rd.jpg');
                         Rst=imread('Rst.jpg');
                         Rx=imread('Rx.jpg');                       
                         emp=imread('emp.jpg');
                         zero=imread('0.jpg');
                         one=imread('1.jpg');
                         two=imread('2.jpg');
                         three=imread('3.jpg');
                         four=imread('4.jpg');
                         five=imread('5.jpg');
                         six=imread('6.jpg');
                         seven=imread('7.jpg');
                         eight=imread('8.jpg');    
                         nine=imread('9.jpg');
                         A=imread('A.jpg');
                         B=imread('B.jpg');
                         C=imread('C.jpg');
                         D=imread('D.jpg');
                         E=imread('E.jpg');
                         F=imread('F.jpg');
                         G=imread('G.jpg');
                         H=imread('H.jpg');
                         I=imread('I.jpg');
                         J=imread('J.jpg');
                         K=imread('K.jpg');
                         L=imread('L.jpg');
                         M=imread('M.jpg');
                         N=imread('N.jpg');
                         O=imread('O.jpg');
                         P=imread('P.jpg');
                         Q=imread('Q.jpg');
                         R=imread('R.jpg');
                         S=imread('S.jpg');
                         T=imread('T.jpg');
                         U=imread('U.jpg');
                         V=imread('V.jpg');
                         W=imread('W.jpg');
                         X=imread('X.jpg');
                         Y=imread('Y.jpg');
                         Z=imread('Z.jpg');
                         BoldA=imread('BoldA.jpg');
                         BoldB=imread('BoldB.jpg');
                         BoldC=imread('BoldC.jpg');
                         BoldD=imread('BoldD.jpg');
                         BoldE=imread('BoldE.jpg');
                         BoldF=imread('BoldF.jpg');
                         BoldG=imread('BoldG.jpg');
                         BoldH=imread('BoldH.jpg');
                         BoldI=imread('BoldI.jpg');
                         BoldJ=imread('BoldJ.jpg');
                         BoldK=imread('BoldK.jpg');
                         BoldL=imread('BoldL.jpg');
                         BoldM=imread('BoldM.jpg');
                         BoldN=imread('BoldN.jpg');
                         BoldO=imread('BoldO.jpg');
                         BoldP=imread('BoldP.jpg');
                         BoldQ=imread('BoldQ.jpg');
                         BoldR=imread('BoldR.jpg');
                         BoldS=imread('BoldS.jpg');
                         BoldT=imread('BoldT.jpg');
                         BoldU=imread('BoldU.jpg');
                         BoldV=imread('BoldV.jpg');
                         BoldW=imread('BoldW.jpg');
                         BoldX=imread('BoldX.jpg');
                         BoldY=imread('BoldY.jpg');
                         BoldZ=imread('BoldZ.jpg');

                         
                    for i=1:81
                        if (strcmp(row1(i),'B■'))
                            row1(i) = {Bsq};
                        elseif (strcmp(row1(i),'B●'))
                            row1(i) = {Bc};
                        elseif (strcmp(row1(i),'B♦'))
                            row1(i) = {Bd};  
                        elseif (strcmp(row1(i),'B✸'))
                            row1(i) = {Bst};   
                        elseif (strcmp(row1(i),'Bx'))
                            row1(i) = {Bx};    
                        elseif (strcmp(row1(i),'B+'))
                            row1(i) = {Bp};
                        elseif (strcmp(row1(i),'G■'))
                            row1(i) = {Gsq};
                        elseif (strcmp(row1(i),'G●'))
                            row1(i) = {Gc};
                        elseif (strcmp(row1(i),'G♦'))
                            row1(i) = {Gd};  
                        elseif (strcmp(row1(i),'G✸'))
                            row1(i) = {Gst};   
                        elseif (strcmp(row1(i),'Gx'))
                            row1(i) = {Gx};    
                        elseif (strcmp(row1(i),'G+'))
                            row1(i) = {Gp};                            
                        elseif (strcmp(row1(i),'Y■'))
                            row1(i) = {Ysq};
                        elseif (strcmp(row1(i),'Y●'))
                            row1(i) = {Yc};
                        elseif (strcmp(row1(i),'Y♦'))
                            row1(i) = {Yd};  
                        elseif (strcmp(row1(i),'Y✸'))
                            row1(i) = {Yst};   
                        elseif (strcmp(row1(i),'Yx'))
                            row1(i) = {Yx};    
                        elseif (strcmp(row1(i),'Y+'))
                            row1(i) = {Yp};
                        elseif (strcmp(row1(i),'R■'))
                            row1(i) = {Rsq};
                        elseif (strcmp(row1(i),'R●'))
                            row1(i) = {Rc};
                        elseif (strcmp(row1(i),'R♦'))
                            row1(i) = {Rd};  
                        elseif (strcmp(row1(i),'R✸'))
                            row1(i) = {Rst};   
                        elseif (strcmp(row1(i),'Rx'))
                            row1(i) = {Rx};    
                        elseif (strcmp(row1(i),'R+'))
                            row1(i) = {Rp};                     
                        elseif (strcmp(row1(i),'.'))
                            row1(i) = {emp}; 
                        elseif (strcmp(row1(i),'0'))
                            row1(i) = {zero}; 
                        elseif (strcmp(row1(i),'1'))
                            row1(i) = {one}; 
                        elseif (strcmp(row1(i),'2'))
                            row1(i) = {two};    
                        elseif (strcmp(row1(i),'3'))
                            row1(i) = {three}; 
                        elseif (strcmp(row1(i),'4'))
                            row1(i) = {four}; 
                        elseif (strcmp(row1(i),'5'))
                            row1(i) = {five};                            
                        elseif (strcmp(row1(i),'6'))
                            row1(i) = {six}; 
                        elseif (strcmp(row1(i),'7'))
                            row1(i) = {seven}; 
                        elseif (strcmp(row1(i),'8'))
                            row1(i) = {eight};
                        elseif (strcmp(row1(i),'9'))
                            row1(i) = {nine};
                        elseif(strcmp(row1(i),'A'))
                            row1(i) = {A};    
                        elseif(strcmp(row1(i),'B'))
                            row1(i) = {B}; 
                        elseif(strcmp(row1(i),'C'))
                            row1(i) = {C}; 
                        elseif(strcmp(row1(i),'D'))
                            row1(i) = {D}; 
                        elseif(strcmp(row1(i),'E'))
                            row1(i) = {E}; 
                        elseif(strcmp(row1(i),'F'))
                            row1(i) = {F}; 
                        elseif(strcmp(row1(i),'G'))
                            row1(i) = {G}; 
                        elseif(strcmp(row1(i),'H'))
                            row1(i) = {H};
                        elseif(strcmp(row1(i),'I'))
                            row1(i) = {I}; 
                        elseif(strcmp(row1(i),'J'))
                            row1(i) = {J}; 
                        elseif(strcmp(row1(i),'K'))
                            row1(i) = {K}; 
                        elseif(strcmp(row1(i),'L'))
                            row1(i) = {L}; 
                        elseif(strcmp(row1(i),'M'))
                            row1(i) = {M}; 
                        elseif(strcmp(row1(i),'N'))
                            row1(i) = {N}; 
                        elseif(strcmp(row1(i),'O'))
                            row1(i) = {O}; 
                        elseif(strcmp(row1(i),'P'))
                            row1(i) = {P}; 
                        elseif(strcmp(row1(i),'Q'))
                            row1(i) = {Q}; 
                        elseif(strcmp(row1(i),'R'))
                            row1(i) = {R}; 
                        elseif(strcmp(row1(i),'S'))
                            row1(i) = {S}; 
                        elseif(strcmp(row1(i),'T'))
                            row1(i) = {T}; 
                        elseif(strcmp(row1(i),'U'))
                            row1(i) = {U}; 
                        elseif(strcmp(row1(i),'V'))
                            row1(i) = {V}; 
                        elseif(strcmp(row1(i),'W'))
                            row1(i) = {W}; 
                        elseif(strcmp(row1(i),'X'))
                            row1(i) = {X}; 
                        elseif(strcmp(row1(i),'Y'))
                                row1(i) = {Y}; 
                        elseif(strcmp(row1(i),'Z'))
                                row1(i) = {Z}; 
                        elseif(strcmp(row1(i),'BoldA'))
                            row1(i) = {BoldA};    
                        elseif(strcmp(row1(i),'BoldB'))
                            row1(i) = {BoldB}; 
                        elseif(strcmp(row1(i),'BoldC'))
                            row1(i) = {BoldC}; 
                        elseif(strcmp(row1(i),'BoldD'))
                            row1(i) = {BoldD}; 
                        elseif(strcmp(row1(i),'BoldE'))
                            row1(i) = {BoldE}; 
                        elseif(strcmp(row1(i),'BoldF'))
                            row1(i) = {BoldF}; 
                        elseif(strcmp(row1(i),'BoldG'))
                            row1(i) = {BoldG}; 
                        elseif(strcmp(row1(i),'BoldH'))
                            row1(i) = {BoldH};
                        elseif(strcmp(row1(i),'BoldI'))
                            row1(i) = {BoldI}; 
                        elseif(strcmp(row1(i),'BoldJ'))
                            row1(i) = {BoldJ}; 
                        elseif(strcmp(row1(i),'BoldK'))
                            row1(i) = {BoldK}; 
                        elseif(strcmp(row1(i),'BoldL'))
                            row1(i) = {BoldL}; 
                        elseif(strcmp(row1(i),'BoldM'))
                            row1(i) = {BoldM}; 
                        elseif(strcmp(row1(i),'BoldN'))
                            row1(i) = {BoldN}; 
                        elseif(strcmp(row1(i),'BoldO'))
                            row1(i) = {BoldO}; 
                        elseif(strcmp(row1(i),'BoldP'))
                            row1(i) = {BoldP}; 
                        elseif(strcmp(row1(i),'BoldQ'))
                            row1(i) = {BoldQ}; 
                        elseif(strcmp(row1(i),'BoldR'))
                            row1(i) = {BoldR}; 
                        elseif(strcmp(row1(i),'BoldS'))
                            row1(i) = {BoldS}; 
                        elseif(strcmp(row1(i),'BoldT'))
                            row1(i) = {BoldT}; 
                        elseif(strcmp(row1(i),'BoldU'))
                            row1(i) = {BoldU}; 
                        elseif(strcmp(row1(i),'BoldV'))
                            row1(i) = {BoldV}; 
                        elseif(strcmp(row1(i),'BoldW'))
                            row1(i) = {BoldW}; 
                        elseif(strcmp(row1(i),'BoldX'))
                            row1(i) = {BoldX}; 
                        elseif(strcmp(row1(i),'BoldY'))
                            row1(i) = {BoldY}; 
                        elseif(strcmp(row1(i),'BoldZ'))
                            row1(i) = {BoldZ};    
                        end
                    end
                    CakeReview = montage(row1,'Size', [9 9]);
%                     CakeReview.title('Review your order');
%                     app.Image.set(gcf,CakeReview)

                    app.TabGroup.SelectedTab = app.ReviewTab;
                    
        end

        % Button pushed function: FixupmyorderButton_2
        function FixupmyorderButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.OrderHereTab;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            imaqreset;
            delete(app);          
        end

        % Button pushed function: CheckButton_2
        function CheckButton_2Pushed(app, event)
            app.LightCurtainBrokenLamp.Color = [0 1 0];
            app.DirectionSwitch.Value = 'Backward';
            app.ConRunButton.Value = 0;
            app.ConRunLamp.Color = [1 0 0];
            app.VacSolSwitch.Value = 'Off';
            app.VacRunButton.Value = 0;
            app.StartUpRobotButton.Visible = 1;
            app.PauseRobotButton.Visible = 0;
            app.ShutDownRobotButton.Visible = 0;
            app.VacSolLamp.Color = [1 0 0];
            app.TextArea.Value = 'Restart Robot using normal procedure.';  
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: CheckButton_4
        function CheckButton_4Pushed(app, event)
            app.ConveyorInterlockBrokenLamp.Color = [0 1 0];
            app.DirectionSwitch.Value = 'Backward';
            app.ConRunButton.Value = 0;
            app.ConRunLamp.Color = [1 0 0];
            app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            app.ReconnectButton_Pressed = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home Tab';
            app.RobotOperationsPanel = uipanel(app.HomeTab);
            app.RobotOperationsPanel.Title = 'Robot Operations';
            app.StartUpRobotButton = uibutton(app.RobotOperationsPanel, 'push');
            app.StartUpRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartUpRobotButtonPushed, true);
            app.StartUpRobotButton.Text = 'Start Up Robot';
            app.ResumeRobotButton = uibutton(app.RobotOperationsPanel, 'push');
            app.ResumeRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeRobotButtonPushed, true);
            app.ResumeRobotButton.Visible = 'off';
            app.ResumeRobotButton.Text = 'Resume Robot';
            app.ShutDownRobotButton = uibutton(app.RobotOperationsPanel, 'push');
            app.ShutDownRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShutDownRobotButtonPushed, true);
            app.ShutDownRobotButton.Visible = 'off';
            app.ShutDownRobotButton.Text = 'Shut Down Robot';
            app.RobotOperationStatusLampLabel = uilabel(app.RobotOperationsPanel);
            app.RobotOperationStatusLampLabel.Text = 'Robot Operation Status';
            app.RobotOperationStatusLamp = uilamp(app.RobotOperationsPanel);
            app.PauseRobotButton = uibutton(app.RobotOperationsPanel, 'push');
            app.PauseRobotButton.ButtonPushedFcn = createCallbackFcn(app, @PauseRobotButtonPushed, true);
            app.PauseRobotButton.Visible = 'off';
            app.PauseRobotButton.Text = 'Pause Robot';
            app.LoginButton = uibutton(app.RobotOperationsPanel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Enable = 'off';
            app.LoginButton.Visible = 'off';
            app.LoginButton.Text = 'Login';
            app.ErrorsPanel = uipanel(app.HomeTab);
            app.ErrorsPanel.Title = 'Errors';
            app.EmergencyStopLamp_2Label = uilabel(app.ErrorsPanel);
            app.EmergencyStopLamp_2Label.Text = 'Emergency Stop';
            app.EmergencyStopLamp = uilamp(app.ErrorsPanel);
            app.LightCurtainBrokenLampLabel = uilabel(app.ErrorsPanel);
            app.LightCurtainBrokenLampLabel.Text = 'Light Curtain Broken';
            app.LightCurtainBrokenLamp = uilamp(app.ErrorsPanel);
            app.ConveyorInterlockBrokenLampLabel = uilabel(app.ErrorsPanel);
            app.ConveyorInterlockBrokenLampLabel.Text = 'Conveyor Interlock Broken';
            app.ConveyorInterlockBrokenLamp = uilamp(app.ErrorsPanel);
            app.RobotMotionLimitationLampLabel = uilabel(app.ErrorsPanel);
            app.RobotMotionLimitationLampLabel.Text = 'Robot Motion Limitation';
            app.RobotMotionLimitationLamp = uilamp(app.ErrorsPanel);
            app.TaskCompletionStatusPanel_2 = uipanel(app.HomeTab);
            app.TaskCompletionStatusPanel_2.Title = 'Task Completion Status';
            app.ReadyforCustomerOrderLampLabel = uilabel(app.TaskCompletionStatusPanel_2);
            app.ReadyforCustomerOrderLampLabel.Text = 'Ready for Customer Order';
            app.ReadyforCustomerOrderLamp = uilamp(app.TaskCompletionStatusPanel_2);
            app.TracingoutEdibleInkTextLamp_3Label = uilabel(app.TaskCompletionStatusPanel_2);
            app.TracingoutEdibleInkTextLamp_3Label.Text = 'Tracing out Edible Ink Text';
            app.TracingoutEdibleInkTextLamp = uilamp(app.TaskCompletionStatusPanel_2);
            app.CAKEREADYLampLabel = uilabel(app.TaskCompletionStatusPanel_2);
            app.CAKEREADYLampLabel.Text = 'CAKE READY';
            app.CAKEREADYLamp = uilamp(app.TaskCompletionStatusPanel_2);
            app.PlacingLetterBlocksLamp_3Label = uilabel(app.TaskCompletionStatusPanel_2);
            app.PlacingLetterBlocksLamp_3Label.Text = 'Placing Letter Blocks';
            app.PlacingLetterBlocksLamp = uilamp(app.TaskCompletionStatusPanel_2);
            app.TextArea = uitextarea(app.HomeTab);
            app.TextArea.Editable = 'off';
            app.DIOsPanel = uipanel(app.HomeTab);
            app.DIOsPanel.Title = 'DIOs';
            app.MotorsONLampLabel = uilabel(app.DIOsPanel);
            app.MotorsONLampLabel.Text = 'Motors ON';
            app.MotorsONLamp = uilamp(app.DIOsPanel);
            app.MotorsEnabledLampLabel = uilabel(app.DIOsPanel);
            app.MotorsEnabledLampLabel.Text = 'Motors Enabled';
            app.MotorsEnabledLamp = uilamp(app.DIOsPanel);
            app.ResetIOsButton = uibutton(app.DIOsPanel, 'push');
            app.ResetIOsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetIOsButtonPushed, true);
            app.ResetIOsButton.Enable = 'off';
            app.ResetIOsButton.Text = 'Reset IOs';
            app.ConveyorLabel = uilabel(app.DIOsPanel);
            app.ConveyorLabel.Text = 'Conveyor';
            app.VacuumLabel = uilabel(app.DIOsPanel);
            app.VacuumLabel.Text = 'Vacuum';
            app.ConRunButton = uibutton(app.DIOsPanel, 'state');
            app.ConRunButton.ValueChangedFcn = createCallbackFcn(app, @ConRunButtonValueChanged, true);
            app.ConRunButton.Text = 'ConRun';
            app.DirectionSwitchLabel = uilabel(app.DIOsPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.DIOsPanel, 'slider');
            app.DirectionSwitch.Items = {'Backward', 'Forward'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = 'Backward';
            app.VacSolSwitchLabel = uilabel(app.DIOsPanel);
            app.VacSolSwitchLabel.Text = 'VacSol';
            app.VacSolSwitch = uiswitch(app.DIOsPanel, 'slider');
            app.VacSolSwitch.ValueChangedFcn = createCallbackFcn(app, @VacSolSwitchValueChanged, true);
            app.VacRunButton = uibutton(app.DIOsPanel, 'state');
            app.VacRunButton.ValueChangedFcn = createCallbackFcn(app, @VacRunButtonValueChanged, true);
            app.VacRunButton.Text = 'VacRun';
            app.ConRunLampLabel = uilabel(app.DIOsPanel);
            app.ConRunLampLabel.Text = 'ConRun';
            app.ConRunLamp = uilamp(app.DIOsPanel);
            app.VacSolLampLabel = uilabel(app.DIOsPanel);
            app.VacSolLampLabel.Text = 'VacSol';
            app.VacSolLamp = uilamp(app.DIOsPanel);
            app.VacRunLampLabel = uilabel(app.DIOsPanel);
            app.VacRunLampLabel.Text = 'VacRun';
            app.VacRunLamp = uilamp(app.DIOsPanel);
            app.ConveyorStatusLamp_2Label = uilabel(app.DIOsPanel);
            app.ConveyorStatusLamp_2Label.Text = 'Conveyor Status';
            app.ConveyorStatusLamp = uilamp(app.DIOsPanel);
            app.ManagerLoginEditFieldLabel = uilabel(app.HomeTab);
            app.ManagerLoginEditFieldLabel.Text = 'Manager Login';
            app.ManagerLoginEditField = uieditfield(app.HomeTab, 'text');
            app.ManagerLoginEditField.ValueChangedFcn = createCallbackFcn(app, @ManagerLoginEditFieldValueChanged, true);
            app.LoginStatusLampLabel = uilabel(app.HomeTab);
            app.LoginStatusLampLabel.Text = 'Login Status';
            app.LoginStatusLamp = uilamp(app.HomeTab);
            app.LogoutButton = uibutton(app.HomeTab, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Enable = 'off';
            app.LogoutButton.Text = 'Logout';
            app.ConnectionStatusLampLabel = uilabel(app.HomeTab);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.HomeTab);
            app.ReconnectButton = uibutton(app.HomeTab, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.Panel = uipanel(app.HomeTab);
            app.CameraFeedTab = uitab(app.TabGroup);
            app.CameraFeedTab.Title = 'CameraFeed';
            app.ConVidAxes = uiaxes(app.CameraFeedTab);
            title(app.ConVidAxes, 'Conveyor Video')
            app.TabVidAxes = uiaxes(app.CameraFeedTab);
            title(app.TabVidAxes, 'Table Video')
            app.TracedAxes = uiaxes(app.CameraFeedTab);
            title(app.TracedAxes, 'Letters Traced')
            xlabel(app.TracedAxes, '')
            ylabel(app.TracedAxes, '')
            app.TracedAxes.XTickLabel = '';
            app.TracedAxes.YTickLabel = '';
            app.StartupTab = uitab(app.TabGroup);
            app.StartupTab.Title = 'Startup';
            app.Label = uilabel(app.StartupTab);
            app.Label.Text = 'Please read and Acknowledge the following SWP in order to use the IRB120';
            app.AcknowledgeButton = uibutton(app.StartupTab, 'push');
            app.AcknowledgeButton.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgeButtonPushed, true);
            app.AcknowledgeButton.Text = 'Acknowledge';
            app.CheckBox = uicheckbox(app.StartupTab);
            app.CheckBox.Text = '     The Robot must only be used between the hours of 8:00am and 6:00pm on University working days.';
            app.CheckBox_2 = uicheckbox(app.StartupTab);
            app.CheckBox_2.Text = '     Robot must only be used while at least one person other than the operator is present.';
            app.CheckBox_3 = uicheckbox(app.StartupTab);
            app.CheckBox_3.Text = '     Footwear that effectively covers and protects the toes and instep must be worn.';
            app.CheckBox_4 = uicheckbox(app.StartupTab);
            app.CheckBox_4.Text = '     Those entering the Robot Cell should have loose clothing and long hair constrained.';
            app.CheckBox_5 = uicheckbox(app.StartupTab);
            app.CheckBox_5.Text = '     Users should ensure the Flex Pendant cable remains untwisted and retracts properly back into its housing.';
            app.CheckBox_6 = uicheckbox(app.StartupTab);
            app.CheckBox_6.Text = '     The Mode Switch on on the External Operator''s Panel should always be switched to the Manual Mode.';
            app.CheckBox_7 = uicheckbox(app.StartupTab);
            app.CheckBox_7.Text = {'     Permission to operate the Robot in automatic mode can only be granted by Mark Whitty, Jim Sanderson '; '     or Stephen Kuhle.'};
            app.ShutdownTab = uitab(app.TabGroup);
            app.ShutdownTab.Title = 'Shutdown';
            app.PleaseensurethefollowingSHUTDOWNproceduresarecompletedLabel = uilabel(app.ShutdownTab);
            app.PleaseensurethefollowingSHUTDOWNproceduresarecompletedLabel.Text = 'Please ensure the following SHUTDOWN procedures are completed:';
            app.Label_5 = uilabel(app.ShutdownTab);
            app.Label_5.Text = 'Ensure the Mode Switch on the External Operators Panel is switched to MANUAL Mode';
            app.Label_6 = uilabel(app.ShutdownTab);
            app.Label_6.Text = {'All digital outputs ''DO10_1'', ''DO10_2'', ''DO10_3'' and ''DO10_4'' are reset to 0 to ensure:'; ''; '                  - the vacuum pump is off;'; '                  - the Gripper Vacuum Pad solenoid valve is closed;'; '                  - and the conveyor is not running.'};
            app.JoggingmustbeperformedtoensureRobotisinHOMEPOSITIONLabel = uilabel(app.ShutdownTab);
            app.JoggingmustbeperformedtoensureRobotisinHOMEPOSITIONLabel.Text = 'Jogging must be performed to ensure Robot is in HOME POSITION ';
            app.Label3_2 = uilabel(app.ShutdownTab);
            app.Label3_2.Text = {' Turn off the Power Switch on the front panel of the Robot Controller '; ''; ''; ' Turn off the Robot Controller Isolation Switch and padlock it in the OFF position.'};
            app.AcknowledgeButton_2 = uibutton(app.ShutdownTab, 'push');
            app.AcknowledgeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AcknowledgeButton_2Pushed, true);
            app.AcknowledgeButton_2.Text = 'Acknowledge';
            app.EStopTab = uitab(app.TabGroup);
            app.EStopTab.Title = 'EStop';
            app.ERROREMERGENCYSTOPLabel = uilabel(app.EStopTab);
            app.ERROREMERGENCYSTOPLabel.Text = 'ERROR: EMERGENCY STOP';
            app.EmergencyStophasbeentriggeredPleaseensurethefollowingLabel = uilabel(app.EStopTab);
            app.EmergencyStophasbeentriggeredPleaseensurethefollowingLabel.Text = 'Emergency Stop has been triggered. Please ensure the following:';
            app.Label_11 = uilabel(app.EStopTab);
            app.Label_11.Text = '1. If an Emergency Stop has occurred, that problem should be resolved before returning to normal operation';
            app.Label_12 = uilabel(app.EStopTab);
            app.Label_12.Text = '2. Following an Emergency Stop, Conveyor Control will need to be restored';
            app.CheckButton_3 = uibutton(app.EStopTab, 'push');
            app.CheckButton_3.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton_3.Text = 'Check';
            app.PleaseclickCheckoncetheproblemisresolvedLabel_3 = uilabel(app.EStopTab);
            app.PleaseclickCheckoncetheproblemisresolvedLabel_3.Text = 'Please click "Check" once the problem is resolved.';
            app.LightTab = uitab(app.TabGroup);
            app.LightTab.Title = 'Light';
            app.ERRORLIGHTCURTAINLabel = uilabel(app.LightTab);
            app.ERRORLIGHTCURTAINLabel.Text = 'ERROR: LIGHT CURTAIN';
            app.LightCurtainshavebeentriggeredPleaseensurethefollowingLabel = uilabel(app.LightTab);
            app.LightCurtainshavebeentriggeredPleaseensurethefollowingLabel.Text = 'Light Curtains have been triggered. Please ensure the following:';
            app.Label_10 = uilabel(app.LightTab);
            app.Label_10.Text = {'1. Press the Light Curtain Restart Switch 1 on the inside of the Robot Cell'; ''; '2. Move outside the Robot Cell'; ' '; '3. The yellow Light Curtain Restart 1 indicator on the Light Curtain Restart Panel should be illuminated.'; ''; '4. Ensure there is no-one in the Robot Cell and the Light Curtain is not obstructed'; ''; '5. Press the Light Curtain Restart Switch 2 on the Light Curtain Restart Panel'; ''; '6. The yellow Light Curtain Restart 1 indicator should turn off; '; '    The green Light Curtain Enabled indicator on the Light Curtain Restart Panel should be illuminated; '; '    The General Stop should be removed and it should be possible to operate the Robot arm.'};
            app.CheckButton_2 = uibutton(app.LightTab, 'push');
            app.CheckButton_2.ButtonPushedFcn = createCallbackFcn(app, @CheckButton_2Pushed, true);
            app.CheckButton_2.Text = 'Check';
            app.PleaseclickCheckoncetheproblemisresolvedLabel_2 = uilabel(app.LightTab);
            app.PleaseclickCheckoncetheproblemisresolvedLabel_2.Text = 'Please click "Check" once the problem is resolved.';
            app.ConveyTab = uitab(app.TabGroup);
            app.ConveyTab.Title = 'Convey';
            app.ERRORCONVEYINTERLOCKTRIGGEREDLabel = uilabel(app.ConveyTab);
            app.ERRORCONVEYINTERLOCKTRIGGEREDLabel.Text = 'ERROR: CONVEY INTERLOCK TRIGGERED';
            app.ConveyorInterlockhavebeentriggeredPleaseensurethefollowingLabel = uilabel(app.ConveyTab);
            app.ConveyorInterlockhavebeentriggeredPleaseensurethefollowingLabel.Text = 'Conveyor Interlock have been triggered. Please ensure the following:';
            app.Label_13 = uilabel(app.ConveyTab);
            app.Label_13.Text = {'1. Press the Conveor Interlock Restart Switch 1 on the outside of the Robot Cell'; ''; ''};
            app.CheckButton_4 = uibutton(app.ConveyTab, 'push');
            app.CheckButton_4.ButtonPushedFcn = createCallbackFcn(app, @CheckButton_4Pushed, true);
            app.CheckButton_4.Text = 'Check';
            app.PleaseclickCheckoncetheproblemisresolvedLabel_4 = uilabel(app.ConveyTab);
            app.PleaseclickCheckoncetheproblemisresolvedLabel_4.Text = 'Please click "Check" once the problem is resolved.';
            app.OrderHereTab = uitab(app.TabGroup);
            app.OrderHereTab.Title = 'Order Here';
            app.DECORATEORDERYOURCAKEHERELabel = uilabel(app.OrderHereTab);
            app.DECORATEORDERYOURCAKEHERELabel.Text = {'DECORATE'; '& ORDER '; 'YOUR CAKE '; 'HERE'};
            app.LetterDropDown_1 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_1.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_1.Value = '.';
            app.ReviewOrderButton = uibutton(app.OrderHereTab, 'push');
            app.ReviewOrderButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewOrderButtonPushed, true);
            app.ReviewOrderButton.Text = 'Review Order';
            app.LetterDropDown_2 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_2.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_2.Value = '.';
            app.LetterDropDown_3 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_3.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_3.Value = '.';
            app.LetterDropDown_4 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_4.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_4.Value = '.';
            app.LetterDropDown_5 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_5.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_5.Value = '.';
            app.LetterDropDown_6 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_6.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_6.Value = '.';
            app.LetterDropDown_7 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_7.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_7.Value = '.';
            app.LetterDropDown_8 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_8.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_8.Value = '.';
            app.LetterDropDown_9 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_9.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_9.Value = '.';
            app.LetterDropDown_10 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_10.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_10.Value = '.';
            app.LetterDropDown_11 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_11.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_11.Value = '.';
            app.LetterDropDown_12 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_12.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_12.Value = '.';
            app.LetterDropDown_13 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_13.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_13.Value = '.';
            app.LetterDropDown_14 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_14.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_14.Value = '.';
            app.LetterDropDown_15 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_15.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_15.Value = '.';
            app.LetterDropDown_16 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_16.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_16.Value = '.';
            app.LetterDropDown_17 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_17.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_17.Value = '.';
            app.LetterDropDown_18 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_18.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_18.Value = '.';
            app.LetterDropDown_19 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_19.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_19.Value = '.';
            app.LetterDropDown_20 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_20.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_20.Value = '.';
            app.LetterDropDown_21 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_21.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_21.Value = '.';
            app.LetterDropDown_22 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_22.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_22.Value = '.';
            app.LetterDropDown_23 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_23.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_23.Value = '.';
            app.LetterDropDown_24 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_24.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_24.Value = '.';
            app.LetterDropDown_25 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_25.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_25.Value = '.';
            app.LetterDropDown_26 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_26.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_26.Value = '.';
            app.LetterDropDown_27 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_27.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_27.Value = '.';
            app.LetterDropDown_28 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_28.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_28.Value = '.';
            app.LetterDropDown_29 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_29.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_29.Value = '.';
            app.LetterDropDown_30 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_30.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_30.Value = '.';
            app.LetterDropDown_31 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_31.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_31.Value = '.';
            app.LetterDropDown_32 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_32.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_32.Value = '.';
            app.LetterDropDown_33 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_33.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_33.Value = '.';
            app.LetterDropDown_34 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_34.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_34.Value = '.';
            app.LetterDropDown_35 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_35.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_35.Value = '.';
            app.LetterDropDown_36 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_36.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_36.Value = '.';
            app.LetterDropDown_37 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_37.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_37.Value = '.';
            app.LetterDropDown_38 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_38.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_38.Value = '.';
            app.LetterDropDown_39 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_39.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_39.Value = '.';
            app.LetterDropDown_40 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_40.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_40.Value = '.';
            app.LetterDropDown_41 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_41.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_41.Value = '.';
            app.LetterDropDown_42 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_42.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_42.Value = '.';
            app.LetterDropDown_43 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_43.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_43.Value = '.';
            app.LetterDropDown_44 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_44.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_44.Value = '.';
            app.LetterDropDown_45 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_45.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_45.Value = '.';
            app.LetterDropDown_46 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_46.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_46.Value = '.';
            app.LetterDropDown_47 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_47.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_47.Value = '.';
            app.LetterDropDown_48 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_48.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_48.Value = '.';
            app.LetterDropDown_49 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_49.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_49.Value = '.';
            app.LetterDropDown_50 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_50.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_50.Value = '.';
            app.LetterDropDown_51 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_51.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_51.Value = '.';
            app.LetterDropDown_52 = uidropdown(app.OrderHereTab);

            app.LetterDropDown_52.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_52.Value = '.';
            app.LetterDropDown_53 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_53.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_53.Value = '.';
            app.LetterDropDown_54 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_54.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_54.Value = '.';
            app.LetterDropDown_55 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_55.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_55.Value = '.';
            app.LetterDropDown_56 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_56.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_56.Value = '.';
            app.LetterDropDown_57 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_57.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_57.Value = '.';
            app.LetterDropDown_58 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_58.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_58.Value = '.';
            app.LetterDropDown_59 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_59.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_59.Value = '.';
            app.LetterDropDown_60 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_60.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_60.Value = '.';
            app.LetterDropDown_61 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_61.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_61.Value = '.';
            app.LetterDropDown_62 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_62.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_62.Value = '.';
            app.LetterDropDown_63 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_63.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_63.Value = '.';
            app.LetterDropDown_64 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_64.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_64.Value = '.';
            app.LetterDropDown_65 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_65.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_65.Value = '.';
            app.LetterDropDown_66 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_66.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_66.Value = '.';
            app.LetterDropDown_67 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_67.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_67.Value = '.';
            app.LetterDropDown_68 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_68.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_68.Value = '.';
            app.LetterDropDown_69 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_69.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_69.Value = '.';
            app.LetterDropDown_70 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_70.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_70.Value = '.';
            app.LetterDropDown_71 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_71.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_71.Value = '.';
            app.LetterDropDown_72 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_72.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_72.Value = '.';
            app.LetterDropDown_73 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_73.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_73.Value = '.';
            app.LetterDropDown_74 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_74.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_74.Value = '.';
            app.LetterDropDown_75 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_75.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_75.Value = '.';
            app.LetterDropDown_76 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_76.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_76.Value = '.';
            app.LetterDropDown_77 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_77.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_77.Value = '.';
            app.LetterDropDown_78 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_78.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_78.Value = '.';
            app.LetterDropDown_79 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_79.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_79.Value = '.';
            app.LetterDropDown_80 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_80.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_80.Value = '.';
            app.LetterDropDown_81 = uidropdown(app.OrderHereTab);
            app.LetterDropDown_81.Items = {'.', 'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J', 'K', 'L', 'M', 'N', 'O', 'P', 'Q', 'R', 'S', 'T', 'U', 'V', 'W', 'X', 'Y', 'Z', 'BoldA', 'BoldB', 'BoldC', 'BoldD', 'BoldE', 'BoldF', 'BoldG', 'BoldH', 'BoldI', 'BoldJ', 'BoldK', 'BoldL', 'BoldM', 'BoldN', 'BoldO', 'BoldP', 'BoldQ', 'BoldR', 'BoldS', 'BoldT', 'BoldU', 'BoldV', 'BoldW', 'BoldX', 'BoldY', 'BoldZ', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9', 'R■', 'R♦', 'R●', 'R+', 'Rx', 'R✸', 'Y■', 'Y♦', 'Y●', 'Y+', 'Yx', 'Y✸', 'G■', 'G♦', 'G●', 'G+', 'Gx', 'G✸', 'B■', 'B♦', 'B●', 'B+', 'Bx', 'B✸'};
            app.LetterDropDown_81.Value = '.';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.IsyourordercorrectIfyesSENDITIfnoFixItLabel = uilabel(app.ReviewTab);
            app.IsyourordercorrectIfyesSENDITIfnoFixItLabel.Text = {'Is your order correct? '; 'If yes, SEND IT!.'; 'If no, Fix It'};
            app.FixupmyorderButton_2 = uibutton(app.ReviewTab, 'push');
            app.FixupmyorderButton_2.ButtonPushedFcn = createCallbackFcn(app, @FixupmyorderButton_2Pushed, true);
            app.FixupmyorderButton_2.Text = 'Fix up my order';
            app.SENDITButton_2 = uibutton(app.ReviewTab, 'push');
            app.SENDITButton_2.Text = 'SEND IT!';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicGUINewRev1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end